classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        CalculationProgressGauge       matlab.ui.control.Gauge
        CalculationProgressGaugeLabel  matlab.ui.control.Label
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        ModeofchangePanel              matlab.ui.container.Panel
        SegmentationButtonGroup        matlab.ui.container.ButtonGroup
        EarthForestButton              matlab.ui.control.ToggleButton
        WaterButton                    matlab.ui.control.ToggleButton
        CityButton                     matlab.ui.control.ToggleButton
        ObserveAllButton               matlab.ui.control.ToggleButton
        Switch2                        matlab.ui.control.Switch
        Switch                         matlab.ui.control.Switch
        ModeofvisualizationPanel       matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        StatisticsAnalysisButton       matlab.ui.control.ToggleButton
        TimeLapseButton                matlab.ui.control.ToggleButton
        DifferenceHighlightsButton     matlab.ui.control.ToggleButton
        LoadLocationButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        image_array{};          % Array that holds original images
        image_array_his{};      % Array that holds the saturation adjusted images
        image_array_rec{};      % Array that hold rectified images
        image_array_rec_seg{};  % Array that hold rectified and segmented images
        nr_files;               % Number of files in array of images
        cur_image;              % Number of current image
        mask_array{};           % Array to save all segmentation masks
        file_names{};           % Array that holds the file names
        transform_array{};      % Array that holds the transformation matrix
    end
    
    methods (Access = private)
        
        % Function to handle the preprocessing of the images
        function results = startpreprocessing(app)    
            
            % Show calculation progress
            app.CalculationProgressGauge.Visible = true;
            app.CalculationProgressGaugeLabel.Visible = true;           
            app.CalculationProgressGauge.Value = 0;           
            drawnow  
            
            % Set number of calculation steps           
            calculation_steps = 100 / ((app.nr_files*6)+(app.nr_files*2)-1);  
            
            % Start image saturation compensation
            starthist(app,calculation_steps);       
            
            % Start image segmentation
            segmentation(app, calculation_steps);
            
            % Image registration first two images
            [im1,im2,transformation] = preprocessing5(app.image_array{1}, app.image_array{2});
            %[im1,im2] = preprocessing4(app.image_array{1}, app.image_array{2}, app.image_array_his{1}, app.image_array_his{2});            
            app.image_array_rec{1} = im1;
            app.image_array_rec{2} = im2; 
            app.transform_array{1} = transformation;
            
            % Update GUI
            app.CalculationProgressGauge.Value = app.CalculationProgressGauge.Value + calculation_steps;
            drawnow
            
            % Image registration rest of images
            for i = 3:app.nr_files
                [~, im2, transformation] = preprocessing5(app.image_array{1}, app.image_array{i});
                %[~,im2] = preprocessing4(app.image_array{1}, app.image_array{i}, app.image_array_his{1}, app.image_array_his{i});
                app.image_array_rec{i} = im2;
                app.transform_array{i-1} = transformation;
                
                % Update GUI
                app.CalculationProgressGauge.Value = app.CalculationProgressGauge.Value + calculation_steps;        
                drawnow
            end     
            
            % Hide calculation progress
            app.CalculationProgressGauge.Visible = false;
            app.CalculationProgressGaugeLabel.Visible = false;
        end
        
        % Function to handle the histogram matching
        function results = starthist(app, calc_steps)
            ref = [app.image_array{:}];
            for i = 1:app.nr_files
                % Select image
                I = app.image_array{i};
                % Adjust image to reference
                app.image_array_his{i} = imhistmatch(I,ref,'method','polynomial');
                % Show output for testing purposes
                %figure;
                %imshowpair(app.image_array{i},app.image_array_his{i},'montage')
                % Update GUI
                app.CalculationProgressGauge.Value = app.CalculationProgressGauge.Value + calc_steps;
                drawnow
            end
        end
        
        % Function to segmented all images
        function results = segmentation(app, calc_steps)
            % Load prototypes
            a = load('segment_characteristics.mat');
            LAB_mean = a.LAB_mean;
            length = size(LAB_mean);            
            % Calculate segmentation over all images
            for i = 1:app.nr_files
                for j = 1:length
                    app.mask_array{i,j} = imsegDeltaE(app.image_array_his{i},LAB_mean{j,1:3},20);
                    % Update GUI
                    app.CalculationProgressGauge.Value = app.CalculationProgressGauge.Value + calc_steps;
                    drawnow
                end          
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadLocationButton
        function LoadLocation(app, event)
                
            % Load all chosen images into FileNames, load path to PathName
            [FileNames,PathName] = uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select your Images (Two minimum)','MultiSelect','on');
            
            % If only one image chosen, selection will be repeated until
            % min two images
            while (isa(FileNames,'char'))
                [FileNames,PathName] = uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select your Images (Two minimum)','MultiSelect','on');
            end
                  
            % Get number of chosen images
            app.nr_files = length(FileNames);
            
            % Create cell array with size of number of files
            app.image_array = {app.nr_files};
            
            % Fill cell array with images
            for i = 1:app.nr_files
                
                % Create whole file name: file path + file name
                file = fullfile(PathName,FileNames{i});
                
                % Read image from file
                image = imread(file);
                
                % Save image in cell array
                app.image_array{i} = image;
                
                % Save file names in cell array
                app.file_names{i} = FileNames{i};
            end
            
            % Enable start button, disable stop button
            app.StartButton.Enable = true;
            app.StopButton.Enable = false;
            
            % Visialize first image in array
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            % Display image and stretch to fill axes
            I = imshow(app.image_array{1}, 'Parent', app.UIAxes, 'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
            % Remove tick labels
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];         
            axis(app.UIAxes,'off');
            % Set visibility to true
            app.UIAxes.Visible = true;
            drawnow
            
            % Start preprocessing: rectify loaded images
            % output saved in image_array_rec
            startpreprocessing(app);
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Enable or disable buttons
            app.LoadLocationButton.Enable = false;
            app.StartButton.Enable = false;
            app.StopButton.Enable = true;
          
            % Check which mode of visualization is set
            % Mode of visualization is time lapse
            if app.TimeLapseButton.Value == true
                if app.ObserveAllButton.Value == true
                    % Show first image of array
                    imshow(app.image_array_rec{1}, 'Parent', app.UIAxes, ...
                        'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]); 
                end
                if app.CityButton.Value == true
                    % Calculate mask on rectified image
                    i1 = app.image_array{1};
                    image_seg = bsxfun(@times, i1, cast(app.mask_array{1,5}, class(i1)));
                    %image_seg = i1 .* uint8(app.mask_array{1,5});
                    app.image_array_rec_seg{1} = image_seg;
                    Rfixed = imref2d(size(i1));
                    
                    for i = 2:app.nr_files
                        image_seg = bsxfun(@times, app.image_array{i}, cast(app.mask_array{i,5}, class(app.image_array{i})));                        
                        app.image_array_rec_seg{i} = imwarp(image_seg,app.transform_array{i-1},'OutputView',Rfixed);
                    end
                    
                    % Show first image of array
                    imshow(app.image_array_rec_seg{1}, 'Parent', app.UIAxes, ...
                        'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                end
                % Set current image counter to one
                app.cur_image = 1;
                
                % Make next and previous button visible
                app.NextButton.Visible = true;
                app.PreviousButton.Visible = true;
            end
            % Mode of visualization is statistics
            if app.StatisticsAnalysisButton.Value == true
                if app.WaterButton.Value == true
                    % Calculate statistics         
                    temp1 = app.mask_array(:,2);
                    temp2 = app.file_names;
                    [~, bar] = Statistic(temp1, 'Snow', temp2);
                    % Display output
                    imshow(bar, 'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]); 
                end
            end
            
            %% TODO: Start function and enable visualization
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Enable or disable buttons
            app.LoadLocationButton.Enable = true;
            app.StartButton.Enable = true;
            app.StopButton.Enable = false;
            
            % Buttons for time lapse function
            app.PreviousButton.Visible = false;
            app.NextButton.Visible = false;
            
            %% TODO: Stop function
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.cur_image == 1
                app.cur_image = app.nr_files;
            else
                app.cur_image = app.cur_image - 1;
            end
            
            if app.ObserveAllButton.Value == true
                imshow(app.image_array_rec{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                    [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
            else
                imshow(app.image_array_rec_seg{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                    [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.cur_image == app.nr_files
                app.cur_image = 1;
            else
                app.cur_image = app.cur_image + 1;
            end
            if app.ObserveAllButton.Value == true
                imshow(app.image_array_rec{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                    [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
            else
                imshow(app.image_array_rec_seg{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                    [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.LoadLocationButton = uibutton(app.GridLayout2, 'push');
            app.LoadLocationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLocation, true);
            app.LoadLocationButton.Text = {'Load Location'; ''};
            app.ModeofvisualizationPanel = uipanel(app.GridLayout2);
            app.ModeofvisualizationPanel.Title = 'Mode of visualization';
            app.ButtonGroup = uibuttongroup(app.ModeofvisualizationPanel);
            app.DifferenceHighlightsButton = uitogglebutton(app.ButtonGroup);
            app.DifferenceHighlightsButton.Text = {'Difference-Highlights'; ''};
            app.DifferenceHighlightsButton.Value = true;
            app.TimeLapseButton = uitogglebutton(app.ButtonGroup);
            app.TimeLapseButton.Text = {'Time-Lapse'; ''};
            app.StatisticsAnalysisButton = uitogglebutton(app.ButtonGroup);
            app.StatisticsAnalysisButton.Text = 'Statistics / Analysis';
            app.ModeofchangePanel = uipanel(app.GridLayout2);
            app.ModeofchangePanel.Title = 'Mode of change';
            app.Switch = uiswitch(app.ModeofchangePanel, 'slider');
            app.Switch.Items = {'Smaller', 'Larger'};
            app.Switch.Value = 'Smaller';
            app.Switch2 = uiswitch(app.ModeofchangePanel, 'slider');
            app.Switch2.Items = {'Slower', 'Faster'};
            app.Switch2.Value = 'Slower';
            app.SegmentationButtonGroup = uibuttongroup(app.ModeofchangePanel);
            app.SegmentationButtonGroup.Title = 'Segmentation';
            app.ObserveAllButton = uitogglebutton(app.SegmentationButtonGroup);
            app.ObserveAllButton.Text = 'Observe All';
            app.ObserveAllButton.Value = true;
            app.CityButton = uitogglebutton(app.SegmentationButtonGroup);
            app.CityButton.Text = 'City';
            app.WaterButton = uitogglebutton(app.SegmentationButtonGroup);
            app.WaterButton.Text = 'Water';
            app.EarthForestButton = uitogglebutton(app.SegmentationButtonGroup);
            app.EarthForestButton.Text = 'Earth / Forest';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.StartButton = uibutton(app.GridLayout3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.Panel_2 = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.Panel_2);
            app.UIAxes.Visible = 'off';
            app.PreviousButton = uibutton(app.Panel_2, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.Panel_2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.CalculationProgressGaugeLabel = uilabel(app.Panel_2);
            app.CalculationProgressGaugeLabel.Visible = 'off';
            app.CalculationProgressGaugeLabel.Text = 'Calculation Progress (%)';
            app.CalculationProgressGauge = uigauge(app.Panel_2, 'circular');
            app.CalculationProgressGauge.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end